classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        ButtonSaveResult  matlab.ui.control.Button
        ButtonDetect      matlab.ui.control.Button
        ButtonLoadImage   matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        LabelResults      matlab.ui.control.Label
        LabelStatus       matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Net = [];
        CurrentImage = [];
        ImagePath = '';
        DetectionBboxes = [];  % Bounding boxy z ostatniej detekcji
        DetectionLabels = [];  % Etykiety z ostatniej detekcji (1=boja, 0=pływak)
        DetectionNumBuoys = 0; % Liczba boi z ostatniej detekcji
        DetectionNumSwimmers = 0; % Liczba pływaków z ostatniej detekcji
    end
    
    methods (Access = private)
        
        function [bboxes, labels, numBuoys, numSwimmers] = performDetection(app, I)
            % Ta funkcja wykonuje całą logikę detekcji
            % Bazuje na kodzie z detect_buoys_and_swimmers.m
        
            [H, W, ~] = size(I);
        
            % 1. Segmentacja pomarańczowej maski
            orangeMask = segment_orange_mask(I);
        
            % 2. Znajdź spójne regiony
            [L, num] = bwlabel(orangeMask);
        
            if num == 0
                bboxes = [];
                labels = [];
                numBuoys = 0;
                numSwimmers = 0;
                return;
            end
        
            % 3. Oblicz cechy dla każdego regionu
            stats = regionprops(L, 'BoundingBox');
            features = zeros(num, 5);
            validIdx = false(num, 1);
        
            for k = 1:num
                regionMask = (L == k);
                feat = compute_region_features(regionMask);
                if ~isempty(feat)
                    features(k, :) = feat;
                    validIdx(k) = true;
                end
            end
        
            if ~any(validIdx)
                bboxes = [];
                labels = [];
                numBuoys = 0;
                numSwimmers = 0;
                return;
            end
        
            % 4. Klasyfikacja przez sieć neuronową
            featuresValid = features(validIdx, :);
            Xnew = featuresValid.';
            Ynew = app.Net(Xnew);
            YnewBin = Ynew > 0.5;
        
            % 5. Przygotuj bounding boxy
            idxValid = find(validIdx);
            numValid = numel(idxValid);
            bboxes = zeros(numValid, 4);
            for i = 1:numValid
                k = idxValid(i);
                bb = stats(k).BoundingBox;
                bboxes(i, :) = bb;
            end
        
            isBuoy = YnewBin == 1;
            isSwimmer = YnewBin == 0;
        
            % 6. Filtrowanie: usuń pływaków wewnątrz boi
            keep = true(numValid, 1);
        
            for i = 1:numValid
                if ~isSwimmer(i)
                    continue;
                end
        
                bbS = bboxes(i, :);
                cx = bbS(1) + bbS(3)/2;
                cy = bbS(2) + bbS(4)/2;
        
                for j = 1:numValid
                    if ~isBuoy(j)
                        continue;
                    end
        
                    bbB = bboxes(j, :);
                    if cx >= bbB(1) && cx <= bbB(1)+bbB(3) && ...
                       cy >= bbB(2) && cy <= bbB(2)+bbB(4)
                        keep(i) = false;
                        break;
                    end
                end
            end
        
            % 7. Filtrowanie: usuń małe boje wewnątrz większych
            for i = 1:numValid
                if ~isBuoy(i) || ~keep(i)
                    continue;
                end
        
                bbSmall = bboxes(i, :);
                areaSmall = bbSmall(3) * bbSmall(4);
        
                for j = 1:numValid
                    if i == j || ~isBuoy(j) || ~keep(j)
                        continue;
                    end
        
                    bbBig = bboxes(j, :);
                    areaBig = bbBig(3) * bbBig(4);
        
                    if areaSmall >= areaBig
                        continue;
                    end
        
                    if bbSmall(1) >= bbBig(1) && ...
                       bbSmall(2) >= bbBig(2) && ...
                       bbSmall(1)+bbSmall(3) <= bbBig(1)+bbBig(3) && ...
                       bbSmall(2)+bbSmall(4) <= bbBig(2)+bbBig(4)
                        keep(i) = false;
                        break;
                    end
                end
            end
        
            % 8. Zastosuj filtrowanie
            YnewBin = YnewBin(keep);
            bboxes = bboxes(keep, :);
        
            % 9. Policz wyniki
            isBuoy = YnewBin == 1;
            isSwimmer = YnewBin == 0;
            numBuoys = sum(isBuoy);
            numSwimmers = sum(isSwimmer);
        
            % 10. Przygotuj etykiety (1 = boja, 0 = pływak)
            labels = YnewBin;
        end

        function displayResults(app, I, bboxes, labels)
            % Wyświetl obraz z zaznaczonymi obiektami
            imshow(I, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Rysuj bounding boxy
            for i = 1:size(bboxes, 1)
                bb = bboxes(i, :);
                if labels(i) == 1
                    % Boja - zielony prostokąt
                    rectangle('Position', bb, 'EdgeColor', 'g', 'LineWidth', 2, ...
                             'Parent', app.UIAxes);
                else
                    % Pływak - niebieski prostokąt
                    rectangle('Position', bb, 'EdgeColor', 'b', 'LineWidth', 2, ...
                             'Parent', app.UIAxes);
                end
            end
        
            hold(app.UIAxes, 'off');
        
            % Aktualizuj tytuł
            numBuoys = sum(labels == 1);
            numSwimmers = sum(labels == 0);
            [~, fn, ~] = fileparts(app.ImagePath);
            app.UIAxes.Title.String = sprintf('%s - Boje: %d, Ludzie: %d', ...
                                              fn, numBuoys, numSwimmers);
        end
        
        % HELPER FUNCTION: Save Results (opcjonalnie)
        
        function saveResults(app, bboxes, labels, numBuoys, numSwimmers)
            % Zapisuje wyniki do plików (podobnie jak w detect_buoys_and_swimmers.m)
        
            if ~exist('results', 'dir')
                mkdir('results');
            end
        
            figDir = fullfile('results', 'figures');
            logDir = fullfile('results', 'logs');
        
            if ~exist(figDir, 'dir')
                mkdir(figDir);
            end
            if ~exist(logDir, 'dir')
                mkdir(logDir);
            end
        
            % Zapisz obraz z detekcją
            [~, baseName, ~] = fileparts(app.ImagePath);
            ts = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
            detFigName = sprintf('%s_detection_%s.png', baseName, ts);
            detFigPath = fullfile(figDir, detFigName);
        
            % Utwórz figurę tymczasową do zapisu
            fig = figure('Visible', 'off');
            ax = axes(fig);
            imshow(app.CurrentImage, 'Parent', ax);
            hold(ax, 'on');
        
            for i = 1:size(bboxes, 1)
                bb = bboxes(i, :);
                if labels(i) == 1
                    rectangle('Position', bb, 'EdgeColor', 'g', 'LineWidth', 2, 'Parent', ax);
                else
                    rectangle('Position', bb, 'EdgeColor', 'b', 'LineWidth', 2, 'Parent', ax);
                end
            end
            hold(ax, 'off');
            title(ax, sprintf('Boje: %d   Ludzie: %d', numBuoys, numSwimmers));
        
            saveas(fig, detFigPath);
            close(fig);
        
            % Zapisz log
            logPath = fullfile(logDir, sprintf('detect_log_%s.txt', ts));
            fid = fopen(logPath, 'w');
            if fid ~= -1
                dtStr = char(datetime('now'));
                fprintf(fid, 'Data: %s\n', dtStr);
                fprintf(fid, 'Plik obrazu: %s\n', app.ImagePath);
                fprintf(fid, 'Boje: %d\n', numBuoys);
                fprintf(fid, 'Ludzie: %d\n', numSwimmers);
                fclose(fid);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                modelPath = fullfile('models', 'net_buoy.mat');
                if exist(modelPath, 'file')
                    data = load(modelPath, 'net');
                    app.Net = data.net;
                    app.LabelStatus.Text = 'Status: Model załadowany pomyślnie';
                    app.LabelStatus.FontColor = [0 0.6 0];
                else
                    app.LabelStatus.Text = 'Status: BŁĄD - Nie znaleziono modelu!';
                    app.LabelStatus.FontColor = [1 0 0];
                    uialert(app.UIFigure, ...
                        sprintf('Nie znaleziono pliku modelu:\n%s\n\nUpewnij się, że model został wytrenowany.', modelPath), ...
                        'Błąd ładowania modelu', 'Icon', 'error');
                end
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas ładowania modelu:\n%s', ME.message), ...
                    'Błąd', 'Icon', 'error');
            end
        end

        % Button pushed function: ButtonLoadImage
        function ButtonLoadImagePushed(app, event)
            [fn, fp] = uigetfile({'*.jpg;*.png;*.jpeg', 'Obrazy (*.jpg, *.png, *.jpeg)'; ...
                                   '*.*', 'Wszystkie pliki (*.*)'}, ...
                                  'Wybierz obraz do analizy');
        
            if isequal(fn, 0)
                % Użytkownik anulował wybór
                return;
            end
        
            try
                % Wczytaj obraz
                imgPath = fullfile(fp, fn);
                I = imread(imgPath);
        
                % Zapisz obraz i ścieżkę
                app.CurrentImage = I;
                app.ImagePath = imgPath;
        
                % Wyświetl obraz w osiach
                imshow(I, 'Parent', app.UIAxes);
                app.UIAxes.Title.String = fn;
        
                % Aktualizuj status
                app.LabelStatus.Text = sprintf('Status: Obraz wczytany - %s', fn);
                app.LabelStatus.FontColor = [0 0 1];
        
                % Wyczyść poprzednie wyniki
                app.LabelResults.Text = 'Wyniki: -';
                app.DetectionBboxes = [];
                app.DetectionLabels = [];
                app.DetectionNumBuoys = 0;
                app.DetectionNumSwimmers = 0;
        
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas wczytywania obrazu:\n%s', ME.message), ...
                    'Błąd', 'Icon', 'error');
            end
        end

        % Button pushed function: ButtonDetect
        function ButtonDetectPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, ...
                    'Najpierw wczytaj obraz!', ...
                    'Brak obrazu', 'Icon', 'warning');
                return;
            end
        
            % Sprawdź czy model został załadowany
            if isempty(app.Net)
                uialert(app.UIFigure, ...
                    'Model nie został załadowany!', ...
                    'Brak modelu', 'Icon', 'error');
                return;
            end
        
            try
                app.LabelStatus.Text = 'Status: Wykonywanie detekcji...';
                app.LabelStatus.FontColor = [1 0.65 0];
                drawnow; % Aktualizuj interfejs
        
                % Wykonaj detekcję
                [bboxes, labels, numBuoys, numSwimmers] = performDetection(app, app.CurrentImage);
        
                % Wyświetl wyniki na obrazie
                displayResults(app, app.CurrentImage, bboxes, labels);
        
                % Aktualizuj status i wyniki
                app.LabelStatus.Text = 'Status: Detekcja zakończona';
                app.LabelStatus.FontColor = [0 0.6 0];
                app.LabelResults.Text = sprintf('Wyniki: Boje: %d | Ludzie: %d', numBuoys, numSwimmers);
        
                % Zapisz wyniki detekcji do properties (do późniejszego zapisu)
                app.DetectionBboxes = bboxes;
                app.DetectionLabels = labels;
                app.DetectionNumBuoys = numBuoys;
                app.DetectionNumSwimmers = numSwimmers;
        
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas detekcji:\n%s', ME.message), ...
                    'Błąd detekcji', 'Icon', 'error');
            end
        end

        % Button pushed function: ButtonSaveResult
        function ButtonSaveResultPushed(app, event)
            if isempty(app.DetectionBboxes) || isempty(app.DetectionLabels)
                uialert(app.UIFigure, ...
                    'Brak wyników detekcji do zapisania!\nNajpierw wykonaj detekcję.', ...
                    'Brak wyników', 'Icon', 'warning');
                return;
            end
        
            % Sprawdź czy obraz jest dostępny
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, ...
                    'Brak obrazu do zapisania!', ...
                    'Brak obrazu', 'Icon', 'warning');
                return;
            end
        
            try
                app.LabelStatus.Text = 'Status: Zapisywanie wyników...';
                app.LabelStatus.FontColor = [1 0.65 0];
                drawnow;
        
                % Zapisz wyniki
                saveResults(app, app.DetectionBboxes, app.DetectionLabels, ...
                            app.DetectionNumBuoys, app.DetectionNumSwimmers);
        
                % Aktualizuj status
                app.LabelStatus.Text = 'Status: Wyniki zapisane pomyślnie';
                app.LabelStatus.FontColor = [0 0.6 0];
        
                uialert(app.UIFigure, ...
                    'Wyniki zostały zapisane w folderze results/', ...
                    'Zapis zakończony', 'Icon', 'success');
        
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD zapisu - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas zapisywania wyników:\n%s', ME.message), ...
                    'Błąd zapisu', 'Icon', 'error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonLoadImage = uibutton(app.LeftPanel, 'push');
            app.ButtonLoadImage.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadImagePushed, true);
            app.ButtonLoadImage.Text = 'Wczytaj';
            app.ButtonDetect = uibutton(app.LeftPanel, 'push');
            app.ButtonDetect.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectPushed, true);
            app.ButtonDetect.Text = 'Wykryj';
            app.ButtonSaveResult = uibutton(app.LeftPanel, 'push');
            app.ButtonSaveResult.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveResultPushed, true);
            app.ButtonSaveResult.Text = 'Zapisz';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LabelStatus = uilabel(app.RightPanel);
            app.LabelStatus.Text = 'Status';
            app.LabelResults = uilabel(app.RightPanel);
            app.LabelResults.Text = 'Wyniki';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end